classdef HausdorffApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Hausdorffdimensioncalculator100UIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        str1Label                       matlab.ui.control.Label
        EnterarraymultiplierEditFieldLabel_2  matlab.ui.control.Label
        EnterarraymultiplierEditField   matlab.ui.control.NumericEditField
        EnterarraymultiplierEditFieldLabel  matlab.ui.control.Label
        EnterstepformEditField_2        matlab.ui.control.NumericEditField
        EnterstepformLabel              matlab.ui.control.Label
        EnterstoppointformEditField_2   matlab.ui.control.NumericEditField
        EnterstoppointformLabel         matlab.ui.control.Label
        EnterstartpointformEditField_2  matlab.ui.control.NumericEditField
        EnterstartpointformEditField_2Label  matlab.ui.control.Label
        EnterstepforrEditField_2        matlab.ui.control.NumericEditField
        EnterstepforrLabel              matlab.ui.control.Label
        EnterstoppointforrEditField     matlab.ui.control.NumericEditField
        EnterstoppointforrEditFieldLabel  matlab.ui.control.Label
        SelectmatfileLabel              matlab.ui.control.Label
        SelectfileButton                matlab.ui.control.Button
        EnterstartpointforrEditField    matlab.ui.control.NumericEditField
        EnterstartpointforrLabel        matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        MessagesTextArea                matlab.ui.control.TextArea
        MessagesLabel                   matlab.ui.control.Label
        NewButton                       matlab.ui.control.Button
        ShifttorightButton              matlab.ui.control.Button
        ShifttoleftButton               matlab.ui.control.Button
        HausdorffdimensioncalculatorLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        button_start = 0;
        button_right_shift = 0;
        button_left_shift = 0;
        button_new = 0;
        start_point_r = 0;
        stop_point_r = 0;
        step_r = 0;
        start_point_m = 0;
        stop_point_m = 0;
        step_m = 0;
        array_multiplier = 0;
    end
    
    methods (Access = private)
        
        
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            app.button_start = 0;
            global data data_array;
            app.MessagesTextArea.FontName = 'Dialog';
            app.MessagesTextArea.FontSize = 16;
            app.MessagesTextArea.FontColor = [0 0.4470 0.7410];
            app.MessagesTextArea.FontWeight = 'bold';
            [filename, path] = uigetfile();
            [data] = load(fullfile(path,filename));
            app.MessagesTextArea.Value = ['Structure with ', sprintf('%d',length(fieldnames(data))), ' field(s) was loaded. Select field needed from list.'];
            % Create three radio buttons in the button group.
            info_str = 'Select field';
            bg = uibuttongroup(app.Hausdorffdimensioncalculator100UIFigure,'Position',[400 220 180 150],'SelectionChangedFcn',@bselection,'Title',info_str,'FontSize',16,'FontName','Dialog','FontWeight','bold','ForegroundColor',[0 0.4470 0.7410],'BackgroundColor',[1,0.87,0.87],'TitlePosition','centertop');
            bg.Scrollable = 'on';
            names_of_fields = fieldnames(data);
            initial_pos = 165;
            button_pressed = {};
            flag_pressed = 0;
            uitogglebutton(bg,'Position',[11 initial_pos-165 140 25],'Text','Initial Button', 'Visible','off');
            for i=1:length(names_of_fields)
                uitogglebutton(bg,'Position',[11 initial_pos-30*(i-1) 140 25],'Text',names_of_fields(i),'BackgroundColor',[1.00,0.78,0.25],'FontSize',16,'FontName','Dialog','FontWeight','bold','FontColor',[0 0.4470 0.7410],'IconAlignmen','center');
            end

            while flag_pressed ~= 1
                pause(1);
            end

            if flag_pressed == 1
                app.MessagesTextArea.Value = [button_pressed, ' selected'];
                bg.Visible = 'off';
                data_array = getfield(data,button_pressed);
                setappdata(app.Hausdorffdimensioncalculator100UIFigure,'data_array',data_array);
                pause(1);
            end

            while(app.button_start ~= 1)
                pause(0.5)
            end
            app.MessagesTextArea.Value = ['Running. This may take from a few seconds to several hours depending on the data and your pc power.'];
            UIAxesButtonDown(app, event);

            function bselection(source,eventdata)
                flag_pressed = 1;
                button_pressed = eventdata.NewValue.Text{1};
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            D = parallel.pool.DataQueue;
            h = waitbar(0, 'Running...');
            afterEach(D, @nUpdateWaitbar);
            p=1;
            data_array = getappdata(app.Hausdorffdimensioncalculator100UIFigure,'data_array');
            data_array = data_array * app.array_multiplier;
            r=[app.stop_point_r:(-1)*app.step_r:app.start_point_r];
            m=[app.stop_point_m:(-1)*app.step_m:app.start_point_m];
            cc=jet(length(m));
            VECTOR_DIM = zeros(length(m),length(r));
            legends = {};
            parfor i=1:length(m)
              m_it=m(i)
              [X, VECTOR_DIM(i,:)]=C(m_it,r,data_array);
              send(D, i);
            end
            delete(h);
            for j=1:length(m)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, lr(r), VECTOR_DIM(j,:), 'Color', cc(j,:), 'LineWidth', 3);
                legends = [legends, 'm = ' + string(m(length(m)-j+1))];
            end
            function nUpdateWaitbar(~)
                waitbar(p/length(m),h);
                p = p + 1;
            end
            legend(app.UIAxes,legends,'Location','southeast','NumColumns',2);
            app.MessagesTextArea.Value = 'Select m needed';
            info_str = 'Select m';
            bg = uibuttongroup(app.Hausdorffdimensioncalculator100UIFigure,'Position',[50 220 180 150],'SelectionChangedFcn',@bselection,'Title',info_str,'FontSize',16,'FontName','Dialog','FontWeight','bold','ForegroundColor',[0 0.4470 0.7410],'BackgroundColor',[1,0.87,0.87],'TitlePosition','centertop');
            bg.Scrollable = 'on';
            initial_pos = 165;
            button_pressed = {};
            flag_pressed = 0;
            uitogglebutton(bg,'Position',[11 initial_pos-165 140 25],'Text','Initial Button', 'Visible','off');
            for i=1:length(m)
                uitogglebutton(bg,'Position',[11 initial_pos-30*(i-1) 140 25],'Text',string(m(i)),'BackgroundColor',[1.00,0.78,0.25],'FontSize',16,'FontName','Dialog','FontWeight','bold','FontColor',[0 0.4470 0.7410],'IconAlignmen','center');
            end

            while flag_pressed ~= 1
                pause(1);
            end

            if flag_pressed == 1
                app.button_new = 0;
                app.button_right_shift = 0;
                app.button_left_shift = 0;
                app.MessagesTextArea.Value = ['m = ', button_pressed, ' was selected'];
                cla(app.UIAxes);
                legend(app.UIAxes, 'off');
                index = find(m==str2num(button_pressed));
                vector = VECTOR_DIM(index,:);
                plot(app.UIAxes, lr(r), vector, 'Color', cc(1,:), 'LineWidth', 3)
                rvec = lr(r);
                i=1;
                j=length(vector);
                while(vector(i)==vector(i+1) && vector(i)) 
                    i = i + 1;
                end
                while(vector(j)==vector(j-1) && vector(j-1)) 
                    j = j - 1;
                end 
                total_approximation_length = j-i;
                ten_percent_gap = round(total_approximation_length*0.1);
                i = i + ten_percent_gap*2;
                a = polyfit(rvec(i:j), vector(i:j), 1);
                t = rvec(i):0.1:rvec(j);
                p2t = polyval(a,t);
                plot(app.UIAxes, t, p2t, 'Color', "red", 'LineWidth', 3);
                app.MessagesTextArea.Value = ['dim=',num2str(a(1),2)];
                bg.Visible = 'off';
                while app.button_new ~= 1      
                    if app.button_right_shift == 1
                        cla(app.UIAxes);
                        i = i + round(ten_percent_gap*0.1);
                        plot(app.UIAxes, lr(r), vector, 'Color', "blue", 'LineWidth', 3)
                        a = polyfit(rvec(i:j), vector(i:j), 1);
                        t = rvec(i):0.1:rvec(j);
                        p2t = polyval(a,t);
                        plot(app.UIAxes, t,p2t, 'Color', "red", 'LineWidth', 3);
                        app.MessagesTextArea.Value = ['dim=',num2str(a(1),2)];
                        app.button_right_shift = 0;
                    end
                    if app.button_left_shift == 1
                        cla(app.UIAxes);
                        j = j - round(ten_percent_gap*0.1);
                        plot(app.UIAxes, lr(r), vector, 'Color', "blue", 'LineWidth', 3)
                        a = polyfit(rvec(i:j), vector(i:j), 1);
                        t = rvec(i):0.1:rvec(j);
                        p2t = polyval(a,t);
                        plot(app.UIAxes, t,p2t, 'Color', "red", 'LineWidth', 3);
                        app.MessagesTextArea.Value = ['dim=',num2str(a(1),2)];
                        app.button_left_shift = 0;
                    end
                    pause(0.5);
                end
                cla(app.UIAxes);
                legend(app.UIAxes, 'off');
                app.MessagesTextArea.Value = '';
            end

            function bselection(source,eventdata)
                flag_pressed = 1;
                button_pressed = eventdata.NewValue.Text;
            end

           
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.button_start = 1;
        end

        % Button pushed function: ShifttoleftButton
        function ShifttoleftButtonPushed(app, event)
            app.button_left_shift = 1;
        end

        % Button pushed function: ShifttorightButton
        function ShifttorightButtonPushed(app, event)
            app.button_right_shift = 1;
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.button_new = 1;
        end

        % Value changed function: EnterstartpointforrEditField
        function EnterstartpointforrEditFieldValueChanged(app, event)
            app.start_point_r = app.EnterstartpointforrEditField.Value;
        end

        % Value changed function: EnterstoppointforrEditField
        function EnterstoppointforrEditFieldValueChanged(app, event)
            app.stop_point_r = app.EnterstoppointforrEditField.Value;
        end

        % Value changed function: EnterstepforrEditField_2
        function EnterstepforrEditField_2ValueChanged(app, event)
            app.step_r = app.EnterstepforrEditField_2.Value;
        end

        % Value changed function: EnterstartpointformEditField_2
        function EnterstartpointformEditField_2ValueChanged(app, event)
            app.start_point_m = app.EnterstartpointformEditField_2.Value;
        end

        % Value changed function: EnterstoppointformEditField_2
        function EnterstoppointformEditField_2ValueChanged(app, event)
            app.stop_point_m = app.EnterstoppointformEditField_2.Value;
        end

        % Value changed function: EnterstepformEditField_2
        function EnterstepformEditField_2ValueChanged(app, event)
            app.step_m = app.EnterstepformEditField_2.Value;
        end

        % Value changed function: EnterarraymultiplierEditField
        function EnterarraymultiplierEditFieldValueChanged(app, event)
            app.array_multiplier = app.EnterarraymultiplierEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Hausdorffdimensioncalculator100UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {464, 464, 464};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {464, 464};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Hausdorffdimensioncalculator100UIFigure = uifigure('Visible', 'off');
            app.Hausdorffdimensioncalculator100UIFigure.Name = 'Hausdorff dimension calculator 1.0.0';
            app.Hausdorffdimensioncalculator100UIFigure.Icon = '140607-middle.png';
            app.Hausdorffdimensioncalculator100UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Hausdorffdimensioncalculator100UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EnterstartpointforrLabel = uilabel(app.LeftPanel);
            app.EnterstartpointforrLabel.Text = '2. Enter start point for r';
            app.EnterstartpointforrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterstartpointforrEditField.ValueChangedFcn = createCallbackFcn(app, @EnterstartpointforrEditFieldValueChanged, true);
            app.SelectfileButton = uibutton(app.LeftPanel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.SelectmatfileLabel = uilabel(app.LeftPanel);
            app.SelectmatfileLabel.Text = '1. Select *.mat file';
            app.EnterstoppointforrEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterstoppointforrEditFieldLabel.Text = '3. Enter stop point for r';
            app.EnterstoppointforrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterstoppointforrEditField.ValueChangedFcn = createCallbackFcn(app, @EnterstoppointforrEditFieldValueChanged, true);
            app.EnterstepforrLabel = uilabel(app.LeftPanel);
            app.EnterstepforrLabel.Text = '4. Enter step for r';
            app.EnterstepforrEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EnterstepforrEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterstepforrEditField_2ValueChanged, true);
            app.EnterstartpointformEditField_2Label = uilabel(app.LeftPanel);
            app.EnterstartpointformEditField_2Label.Text = '5. Enter start point for m';
            app.EnterstartpointformEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EnterstartpointformEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterstartpointformEditField_2ValueChanged, true);
            app.EnterstoppointformLabel = uilabel(app.LeftPanel);
            app.EnterstoppointformLabel.Text = '6. Enter stop point for m';
            app.EnterstoppointformEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EnterstoppointformEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterstoppointformEditField_2ValueChanged, true);
            app.EnterstepformLabel = uilabel(app.LeftPanel);
            app.EnterstepformLabel.Text = '7. Enter step for m';
            app.EnterstepformEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EnterstepformEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterstepformEditField_2ValueChanged, true);
            app.EnterarraymultiplierEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterarraymultiplierEditFieldLabel.Text = '8. Enter array multiplier';
            app.EnterarraymultiplierEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterarraymultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @EnterarraymultiplierEditFieldValueChanged, true);
            app.EnterarraymultiplierEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.EnterarraymultiplierEditFieldLabel_2.Text = '9. Start computations';
            app.str1Label = uilabel(app.LeftPanel);
            app.str1Label.Text = 's.t.: r ≠ 1';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, '|log(r)|')
            ylabel(app.UIAxes, '|log(C)|')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.HausdorffdimensioncalculatorLabel = uilabel(app.CenterPanel);
            app.HausdorffdimensioncalculatorLabel.Text = 'Hausdorff dimension calculator';
            app.ShifttoleftButton = uibutton(app.CenterPanel, 'push');
            app.ShifttoleftButton.ButtonPushedFcn = createCallbackFcn(app, @ShifttoleftButtonPushed, true);
            app.ShifttoleftButton.Text = 'Shift to left';
            app.ShifttorightButton = uibutton(app.CenterPanel, 'push');
            app.ShifttorightButton.ButtonPushedFcn = createCallbackFcn(app, @ShifttorightButtonPushed, true);
            app.ShifttorightButton.Text = 'Shift to right';
            app.NewButton = uibutton(app.CenterPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.MessagesLabel = uilabel(app.CenterPanel);
            app.MessagesLabel.Text = 'Messages ⇨';
            app.MessagesTextArea = uitextarea(app.CenterPanel);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.Hausdorffdimensioncalculator100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HausdorffApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Hausdorffdimensioncalculator100UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Hausdorffdimensioncalculator100UIFigure)
        end
    end
end